classdef CometsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OrganismDetailsPanel         matlab.ui.container.Panel
        SelectModelDropDownLabel     matlab.ui.control.Label
        SelectModelDropDown          matlab.ui.control.DropDown
        BiomassugSpinnerLabel        matlab.ui.control.Label
        BiomassugSpinner             matlab.ui.control.Spinner
        LayoutDetailsPanel           matlab.ui.container.Panel
        XDimSpinnerLabel             matlab.ui.control.Label
        XDimSpinner                  matlab.ui.control.Spinner
        YDimSpinnerLabel             matlab.ui.control.Label
        YDimSpinner                  matlab.ui.control.Spinner
        ExecutionPanel               matlab.ui.container.Panel
        ExecutionDirectoryEditFieldLabel  matlab.ui.control.Label
        ExecutionDirectoryEditField  matlab.ui.control.EditField
        SelectButton                 matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        RunningLabel                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function layout = assembleLayout(app,model)
            layout = createLayout(model);
            layout = setDims(layout,app.XDimSpinner.Value,app.YDimSpinner.Value);
            layout = setInitialPop(layout,'colonies',app.BiomassugSpinner.Value * 10^-6,false);
            layout = addCircularBarrier(layout); %make a circular pitri dish
            
            %set any params
            layout.params.writeBiomassLog = true;
            layout.params.maxCycles = 10;
            
            %set the media
            layout = setInitialMedia(layout,layout.mets,1);
        end
    
    end
    

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Function to assemble the layout, create files, then run it
            
            app.RunningLabel.Text = 'Processing...';
            %load the model
            modelSelection = app.SelectModelDropDown.Value;
            model = load(modelSelection);
            if isfield(model,'model')
                model = model.model;
            elseif isfield(model,'iJO1366')
                    model = model.iJO1366;
            end
            
            
            %build the layout
            layout = assembleLayout(app,model);
            
            %create files
            runDir = app.ExecutionDirectoryEditField.Value;
            if ~exist(runDir,'dir')
                runDir = pwd;
            end
            createCometsFiles(layout,runDir);
            
            %run
            app.RunningLabel.Text = 'Running...';
            runCometsOnDirectory(runDir);
            app.RunningLabel.Text = 'Done!';
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            runDir = uigetdir(pwd,'Select folder for COMETS files');
            app.ExecutionDirectoryEditField.Value = runDir;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OrganismDetailsPanel = uipanel(app.UIFigure);
            app.OrganismDetailsPanel.Title = 'Organism Details';
            app.SelectModelDropDownLabel = uilabel(app.OrganismDetailsPanel);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.OrganismDetailsPanel);
            app.SelectModelDropDown.Items = {'E.coli Core', 'E.coli iJO1366'};
            app.SelectModelDropDown.ItemsData = {'models/ecoli_core_model.mat', 'models/ecoli_iJO1366.mat'};
            app.SelectModelDropDown.Value = 'models/ecoli_core_model.mat';
            app.BiomassugSpinnerLabel = uilabel(app.OrganismDetailsPanel);
            app.BiomassugSpinnerLabel.Text = 'Biomass (ug)';
            app.BiomassugSpinner = uispinner(app.OrganismDetailsPanel);
            app.BiomassugSpinner.Step = 0.1;
            app.BiomassugSpinner.Limits = [0 Inf];
            app.BiomassugSpinner.Value = 1;
            app.LayoutDetailsPanel = uipanel(app.UIFigure);
            app.LayoutDetailsPanel.Title = 'Layout Details';
            app.XDimSpinnerLabel = uilabel(app.LayoutDetailsPanel);
            app.XDimSpinnerLabel.Text = 'X Dim';
            app.XDimSpinner = uispinner(app.LayoutDetailsPanel);
            app.XDimSpinner.Limits = [0 100];
            app.XDimSpinner.Value = 11;
            app.YDimSpinnerLabel = uilabel(app.LayoutDetailsPanel);
            app.YDimSpinnerLabel.Text = 'Y Dim';
            app.YDimSpinner = uispinner(app.LayoutDetailsPanel);
            app.YDimSpinner.Limits = [0 100];
            app.YDimSpinner.Value = 11;
            app.ExecutionPanel = uipanel(app.UIFigure);
            app.ExecutionPanel.Title = 'Execution';
            app.ExecutionDirectoryEditFieldLabel = uilabel(app.ExecutionPanel);
            app.ExecutionDirectoryEditFieldLabel.Text = 'Execution Directory';
            app.ExecutionDirectoryEditField = uieditfield(app.ExecutionPanel, 'text');
            app.SelectButton = uibutton(app.ExecutionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select...';
            app.RunButton = uibutton(app.ExecutionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run!';
            app.RunningLabel = uilabel(app.ExecutionPanel);
            app.RunningLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CometsGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end